classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        TabGroup                     matlab.ui.container.TabGroup
        Processar                    matlab.ui.container.Tab
        ImportarFicheirosButton      matlab.ui.control.Button
        FicheirosaprocessarTextArea  matlab.ui.control.TextArea
        FicheirosaprocessarLabel     matlab.ui.control.Label
        CordenadasdaMetaLabel        matlab.ui.control.Label
        Y2EditField                  matlab.ui.control.NumericEditField
        Y2EditFieldLabel             matlab.ui.control.Label
        X2EditField                  matlab.ui.control.NumericEditField
        X2EditFieldLabel             matlab.ui.control.Label
        Y1EditField                  matlab.ui.control.NumericEditField
        Y1EditFieldLabel             matlab.ui.control.Label
        X1EditField                  matlab.ui.control.NumericEditField
        X1EditFieldLabel             matlab.ui.control.Label
        ProcessarButton              matlab.ui.control.Button
        GraficosTab                  matlab.ui.container.Tab
        ImportFileButton             matlab.ui.control.Button
        AplicaodeTelemetriaLabel     matlab.ui.control.Label
        TrackButton                  matlab.ui.control.Button
        VoltaSpinner                 matlab.ui.control.Spinner
        VoltaSpinnerLabel            matlab.ui.control.Label
        DataInput                    matlab.ui.control.DropDown
        FileLabel                    matlab.ui.control.Label
        EixoYListBoxLabel            matlab.ui.control.Label
        EixoYListBox                 matlab.ui.control.ListBox
        UIAxes                       matlab.ui.control.UIAxes
        UIAxes2                      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        lap              % Variável a qual fica salvo todos os dados importados e separados por voltas
        lapNum           % Salva o numero da volta sendo analisada no momento
        dataX            % Dados que serão plotados no eixo x 
        dataY            % Dados que serão plotados no eixo y 
        dataTypeY        % String com o nome do dado selecionado 
        cursorPoints     % Vector com os tempos dos pontos selecionados pelo utilizador com o rato
        interpolatedData
        file_paths       % Paths dos ficheiros que vão ser processados
        file_paths_processed
    end
    
    methods (Access = private)
        
        function plotData(app)
% Utilizamos um loop for para podermos imprimir multiplos dados no mesmo grafico            
             for ii = 1:length(app.dataTypeY)
                             
                    switch app.dataTypeY{ii}                                        
                        case 'Speed [Km/h]'
                            app.dataY = app.lap(app.lapNum).gps(:,3);
                            app.dataX = app.lap(app.lapNum).gps(:,4);
                        case 'GPS'
                            app.dataY = app.lap(app.lapNum).gps(:,1);
                            app.dataX = app.lap(app.lapNum).gps(:,2);
                        case 'Target id [A]'
                            app.dataY = app.lap(app.lapNum).controller.n1(:,1);
                            app.dataX = app.lap(app.lapNum).controller.n1(:,5);
                        case 'Target iq [A]'
                            app.dataY = app.lap(app.lapNum).controller.n1(:,2);
                            app.dataX = app.lap(app.lapNum).controller.n1(:,5);
                        case 'id [A]'
                            app.dataY = app.lap(app.lapNum).controller.n1(:,3);
                            app.dataX = app.lap(app.lapNum).controller.n1(:,5);
                        case 'iq [A]'
                            app.dataY = app.lap(app.lapNum).controller.n1(:,4);
                            app.dataX = app.lap(app.lapNum).controller.n1(:,5);
                        case 'Ud [V]'
                            app.dataY = app.lap(app.lapNum).controller.n2(:,1);
                            app.dataX = app.lap(app.lapNum).controller.n2(:,5);
                        case 'Uq [V]'
                            app.dataY = app.lap(app.lapNum).controller.n2(:,2);
                            app.dataX = app.lap(app.lapNum).controller.n2(:,5);
                        case 'Voltage modulation'
                            app.dataY = app.lap(app.lapNum).controller.n2(:,3);
                            app.dataX = app.lap(app.lapNum).controller.n2(:,5);
                        case 'AC Voltage [V]'
                            app.dataY = app.lap(app.lapNum).controller.n2(:,4);
                            app.dataX = app.lap(app.lapNum).controller.n2(:,5);
                        case 'AC Current [A]'
                            app.dataY = app.lap(app.lapNum).controller.n3(:,1);
                            app.dataX = app.lap(app.lapNum).controller.n3(:,5);
                        case 'Max iq allowed [A]'
                            app.dataY = app.lap(app.lapNum).controller.n3(:,2);
                            app.dataX = app.lap(app.lapNum).controller.n3(:,5);
                        case 'Motor Temperature [°C]'
                            app.dataY = app.lap(app.lapNum).controller.n3(:,3);
                            app.dataX = app.lap(app.lapNum).controller.n3(:,5);
                        case 'Battery Voltage (Controller) [V]'
                            app.dataY = app.lap(app.lapNum).controller.n3(:,4);
                            app.dataX = app.lap(app.lapNum).controller.n3(:,5);
                        case 'Cap Voltage [V]'
                            app.dataY = app.lap(app.lapNum).controller.n4(:,1);
                            app.dataX = app.lap(app.lapNum).controller.n4(:,5);
                        case 'Heatsink Temperature [°C]'
                            app.dataY = app.lap(app.lapNum).controller.n4(:,2);
                            app.dataX = app.lap(app.lapNum).controller.n4(:,5);
                        case 'Battery Current (Controller) [A]'
                            app.dataY = app.lap(app.lapNum).controller.n4(:,3);
                            app.dataX = app.lap(app.lapNum).controller.n4(:,5);
                        case 'Torque [N.m]'
                            app.dataY = app.lap(app.lapNum).controller.n4(:,4);
                            app.dataX = app.lap(app.lapNum).controller.n4(:,5);
                        case 'Engine RPM'
                            app.dataY = app.lap(app.lapNum).controller.n5(:,1);
                            app.dataX = app.lap(app.lapNum).controller.n5(:,4); 
                        case 'Throttle Voltage [V]'
                            app.dataY = app.lap(app.lapNum).controller.n5(:,2);
                            app.dataX = app.lap(app.lapNum).controller.n5(:,4);
                        case 'Throttle'
                            app.dataY = app.lap(app.lapNum).controller.n5(:,3);
                            app.dataX = app.lap(app.lapNum).controller.n5(:,4);
                        case 'Battery Pack-1 Voltage [V]'    
                            app.dataY = app.lap(app.lapNum).battery.assembly(:,1);
                            app.dataX = app.lap(app.lapNum).battery.assembly(:,9);
                        case 'Battery Pack-2 Voltage [V]'    
                            app.dataY = app.lap(app.lapNum).battery.assembly(:,2);
                            app.dataX = app.lap(app.lapNum).battery.assembly(:,9);
                        case 'Battery Pack-3 Voltage [V]'    
                            app.dataY = app.lap(app.lapNum).battery.assembly(:,3);
                            app.dataX = app.lap(app.lapNum).battery.assembly(:,9);
                        case 'Battery Pack-4 Voltage [V]'    
                            app.dataY = app.lap(app.lapNum).battery.assembly(:,4);
                            app.dataX = app.lap(app.lapNum).battery.assembly(:,9);
                        case 'Battery Pack-5 Voltage [V]'    
                            app.dataY = app.lap(app.lapNum).battery.assembly(:,5);
                            app.dataX = app.lap(app.lapNum).battery.assembly(:,9);                    
                        case 'Battery Assembly Voltage [V]'
                            app.dataY = app.lap(app.lapNum).battery.assembly(:,6);
                            app.dataX = app.lap(app.lapNum).battery.assembly(:,9);    
                        case 'Battery maximum temperature [°C]'    
                             app.dataY = app.lap(app.lapNum).battery.assembly(:,7);
                             app.dataX = app.lap(app.lapNum).battery.assembly(:,9);
                        case 'Pack-1 V1 [V]'    
                             app.dataY = app.lap(app.lapNum).battery.pack1(:,1);
                             app.dataX = app.lap(app.lapNum).battery.pack1(:,9);     
                        case 'Pack-1 V2 [V]'    
                             app.dataY = app.lap(app.lapNum).battery.pack1(:,2);
                             app.dataX = app.lap(app.lapNum).battery.pack1(:,9);                    
                        case 'Pack-1 V3 [V]'    
                             app.dataY = app.lap(app.lapNum).battery.pack1(:,3);
                             app.dataX = app.lap(app.lapNum).battery.pack1(:,9);
                        case 'Pack-1 V4 [V]'    
                             app.dataY = app.lap(app.lapNum).battery.pack1(:,4);
                             app.dataX = app.lap(app.lapNum).battery.pack1(:,9);
                        case 'Pack-1 V5 [V]'    
                             app.dataY = app.lap(app.lapNum).battery.pack1(:,5);
                             app.dataX = app.lap(app.lapNum).battery.pack1(:,9);
                        case 'Pack-2 V1 [V]'    
                             app.dataY = app.lap(app.lapNum).battery.pack2(:,1);
                             app.dataX = app.lap(app.lapNum).battery.pack2(:,9);     
                        case 'Pack-2 V2 [V]'    
                             app.dataY = app.lap(app.lapNum).battery.pack2(:,2);
                             app.dataX = app.lap(app.lapNum).battery.pack2(:,9);                    
                        case 'Pack-2 V3 [V]'    
                             app.dataY = app.lap(app.lapNum).battery.pack2(:,3);
                             app.dataX = app.lap(app.lapNum).battery.pack2(:,9);
                        case 'Pack-2 V4 [V]'    
                             app.dataY = app.lap(app.lapNum).battery.pack2(:,4);
                             app.dataX = app.lap(app.lapNum).battery.pack2(:,9);
                        case 'Pack-2 V5 [V]'    
                             app.dataY = app.lap(app.lapNum).battery.pack2(:,5);
                             app.dataX = app.lap(app.lapNum).battery.pack2(:,9);
                        case 'Pack-3 V1 [V]'    
                             app.dataY = app.lap(app.lapNum).battery.pack3(:,1);
                             app.dataX = app.lap(app.lapNum).battery.pack3(:,9);     
                        case 'Pack-3 V2 [V]'    
                             app.dataY = app.lap(app.lapNum).battery.pack3(:,2);
                             app.dataX = app.lap(app.lapNum).battery.pack3(:,9);                    
                        case 'Pack-3 V3 [V]'    
                             app.dataY = app.lap(app.lapNum).battery.pack3(:,3);
                             app.dataX = app.lap(app.lapNum).battery.pack3(:,9);
                        case 'Pack-3 V4 [V]'    
                             app.dataY = app.lap(app.lapNum).battery.pack3(:,4);
                             app.dataX = app.lap(app.lapNum).battery.pack3(:,9);
                        case 'Pack-3 V5 [V]'    
                             app.dataY = app.lap(app.lapNum).battery.pack3(:,5);
                             app.dataX = app.lap(app.lapNum).battery.pack3(:,9);
                        case 'Pack-4 V1 [V]'    
                             app.dataY = app.lap(app.lapNum).battery.pack4(:,1);
                             app.dataX = app.lap(app.lapNum).battery.pack4(:,9);     
                        case 'Pack-4 V2 [V]'    
                             app.dataY = app.lap(app.lapNum).battery.pack4(:,2);
                             app.dataX = app.lap(app.lapNum).battery.pack4(:,9);                    
                        case 'Pack-4 V3 [V]'    
                             app.dataY = app.lap(app.lapNum).battery.pack4(:,3);
                             app.dataX = app.lap(app.lapNum).battery.pack4(:,9);
                        case 'Pack-4 V4 [V]'    
                             app.dataY = app.lap(app.lapNum).battery.pack4(:,4);
                             app.dataX = app.lap(app.lapNum).battery.pack4(:,9);
                        case 'Pack-4 V5 [V]'    
                             app.dataY = app.lap(app.lapNum).battery.pack4(:,5);
                             app.dataX = app.lap(app.lapNum).battery.pack4(:,9);
                        case 'Pack-5 V1 [V]'    
                             app.dataY = app.lap(app.lapNum).battery.pack5(:,1);
                             app.dataX = app.lap(app.lapNum).battery.pack5(:,9);     
                        case 'Pack-5 V2 [V]'    
                             app.dataY = app.lap(app.lapNum).battery.pack5(:,2);
                             app.dataX = app.lap(app.lapNum).battery.pack5(:,9);                    
                        case 'Pack-5 V3 [V]'    
                             app.dataY = app.lap(app.lapNum).battery.pack5(:,3);
                             app.dataX = app.lap(app.lapNum).battery.pack5(:,9);
                        case 'Pack-5 V4 [V]'    
                             app.dataY = app.lap(app.lapNum).battery.pack5(:,4);
                             app.dataX = app.lap(app.lapNum).battery.pack5(:,9);
                        case 'Pack-5 V5 [V]'    
                             app.dataY = app.lap(app.lapNum).battery.pack5(:,5);
                             app.dataX = app.lap(app.lapNum).battery.pack5(:,9);
                        
                    end
                    
                    plot(app.UIAxes,app.dataX,app.dataY,'LineWidth',1.5); 
                    xlabel(app.UIAxes,'Tempo(s)'); 
                    app.UIAxes.FontWeight = 'bold';          
                    grid(app.UIAxes,'on');
                    grid(app.UIAxes,'minor');
                    app.UIAxes.GridAlpha = 0.075;
                    
                    hold(app.UIAxes,'on');
                    
                    
        %             No caso de dados em falta o gráfico anterior será apagado
                    if isempty(app.dataY)
                        plot(app.UIAxes,[0 0],[0 0]);
                    end
                                                                  
             end
             
                legend(app.UIAxes,app.dataTypeY);
                hold(app.UIAxes,'off')
           end  
        function  plotGPS(app)
            
            plot(app.UIAxes2,app.lap(app.lapNum).gps(:,2),app.lap(app.lapNum).gps(:,1));                          
            hold(app.UIAxes2,'on');
            set(app.UIAxes2,'XTick',[], 'YTick', []);
            xlabel(app.UIAxes2,'');
            ylabel(app.UIAxes2,'');
            title(app.UIAxes2,'GPS')

            cursorGPSpoints(app);
            plot(app.UIAxes2,app.interpolatedData(:,1),app.interpolatedData(:,2),"o","LineWidth",1.5);
            
            hold(app.UIAxes2,'off');


        end
        
        function cursorGPSpoints(app)
        % Recebe os dados da seleção com o Rato feita pelo utilizador e realiza 2
        % interpolações para obter os valores da long e lat correspondentes

            lat = app.lap(app.lapNum).gps(:,1);
            long = app.lap(app.lapNum).gps(:,2);
            gpsTime = app.lap(app.lapNum).gps(:,4);
            app.interpolatedData = zeros(length(app.cursorPoints),2);

            for ii = 1:length(app.cursorPoints)
                app.interpolatedData(ii,1) = interp1(gpsTime,long,app.cursorPoints(ii));               
            end
            for ii = 1:length(app.cursorPoints)
                app.interpolatedData(ii,2) = interp1(gpsTime,lat,app.cursorPoints(ii));               
            end
        end
    end   

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
          %TAB1
            app.file_paths = {};

          %TAB2
            % dataTypeY needs to be defined 
            app.dataTypeY = app.EixoYListBox.Value;
            % Varias entradas de Data Input
            app.file_paths_processed = {};
            app.DataInput.Items = file_names;
            if ~isempty(file_names)
                app.DataInput.Value = file_names{1};
                title(app.UIAxes, {app.DataInput.Value; ''},'Interpreter','none'); %Interpreter none is to enable underscores in names
            end % se no tiver encontrado ficheiros de data apresenta msg de erro (definida por default na inicializacao da legenda)
            % Carregamento dos dados
            load(fullfile('GUI/DataProcessada/',app.DataInput.Value ),"data") ;
            app.lap = data;                    
           % Spinner seleção volta    
            app.lapNum = app.VoltaSpinner.Value;           
            app.VoltaSpinner.Limits = [1,length(app.lap)];

                            
            plotData(app);
            plotGPS(app);

            
        end

        % Value changed function: EixoYListBox
        function EixoYListBoxValueChanged(app, event)
% app.dataTypeY eh um string array uma vez que podem ser feitas
% multiplas selecoesde dados
            app.dataTypeY = app.EixoYListBox.Value;
            plotData(app);
            
        end

        % Value changed function: VoltaSpinner
        function VoltaSpinnerValueChanged(app, event)
            app.lapNum = app.VoltaSpinner.Value;
            plotData(app);
            plotGPS(app);
            
        end

        % Button pushed function: TrackButton
        function TrackButtonPushed(app, event)
% Funcao responsavel pela obtencao do ponto 'clicado' pelo utilizador 
            
% Esta função invoca o data cursor mode e cria um dcm( data cursor management). 
% Do dcm conseguimos tirar dados do cursor que em seguida são retiradas
% apenas os tempos para a realização da correspondência com o GPS.

            datacursormode(app.UIFigure,'on');
            dcm = datacursormode(app.UIFigure);
            aux = getCursorInfo(dcm);

        % Por algum motivo desconhecido não foi possivel usar vetorização.
        % Para contornar isto foi utilizado um loop for

            app.cursorPoints = zeros(length(aux),1);  % Necessário para exclusão de pontos
            
            for i = 1:length(aux)
                app.cursorPoints(i) = aux(i).Position(1);
            end

            datacursormode(app.UIFigure,'off');
            plotGPS(app);

        end

        % Value changed function: DataInput
        function DataInputChanged(app, event)
            [~,name,~] = fileparts(app.DataInput.Value);
            title(app.UIAxes, {name; ''},'Interpreter','none');
            load(fullfile(app.DataInput.Value),"data") ;
            %avoids segFault when changing from a file to another
            app.VoltaSpinner.Value = 1;
            app.VoltaSpinner.Limits = [1,length(data)];
            app.lapNum = app.VoltaSpinner.Value;     
            app.lap = data; 
            plotData(app);
            plotGPS(app);
            
        end

        % Button pushed function: ProcessarButton
        function ProcessarButtonPushed(app, event)
            if numel(app.file_paths) == 0
                return
            end
            output_folder = fullfile(pwd, 'output'); % create a subfolder named "output" in the same directory where app is running
            if ~exist(output_folder, 'dir')
                mkdir(output_folder);
            end
            for i = 1:numel(app.file_paths)
                %data = getAllData(app.file_paths{i},[app.X1EditField.Value,app.X2EditField.Value; app.Y1EditField.Value,app.Y2EditField.Value]);
                [battery,gps,susp,controller,temp] = dataProces(app.file_paths{i});
                data = getLappedData(battery,gps,susp,controller,temp, ...
                                [39.2671;-9.19017]);
                %data = getAllData(app.file_paths{i},[-9.188870809998459,-9.188762851087874; 39.26642438391545,39.26641140518718]);
                data_struct = struct('data', data);
                %save .mat file to 
                [~, name, ~] = fileparts(app.file_paths{i});
                output_file = fullfile(output_folder, [name, '_processed', '.mat']); % append '_processed' to filename
                save(output_file, '-struct', 'data_struct');
            end
        end

        % Button pushed function: ImportarFicheirosButton
        function ImportarFicheirosButtonPushed(app, event)
            [filename, pathname] = uigetfile('*.txt', 'Select one or more files', 'MultiSelect', 'on');
            if ~isequal(filename,0) % user canceled importation
                if iscell(filename)
                    % add each of the chosen path files
                    for i = 1:numel(filename)
                        app.file_paths{end+1} = fullfile(pathname, filename{i});
                    end
                else
                    app.file_paths{end+1} = fullfile(pathname, filename);
                end
                %remove duplicates paths if chosen by user
                app.file_paths = unique(app.file_paths);
                %refreshes display showing Selected Imported Files
                app.FicheirosaprocessarTextArea.Value = strjoin(app.file_paths,newline);
            end
        end

        % Button pushed function: ImportFileButton
        function ImportFileButtonPushed(app, event)
            [filename, pathname] = uigetfile('*.mat', 'Select one or more files', 'MultiSelect', 'on');
            if ~isequal(filename,0)
                if iscell(filename)
                    % add each of the chosen path files
                    for i = 1:numel(filename)
                        app.file_paths_processed{end+1} = fullfile(pathname, filename{i});
                    end
                else
                    app.file_paths_processed{end+1} = fullfile(pathname, filename);
                end
                %remove duplicates paths if chosen by user
                app.file_paths_processed = unique(app.file_paths_processed);
                %refreshes display showing Selected Processed Files
                file_names = {};
                    for i = 1:length(app.file_paths_processed)
                        [~,name,~] = fileparts(app.file_paths_processed{i});
                        file_names{end+1} = name;
                    end
                app.DataInput.Items = file_names;
                app.DataInput.ItemsData = app.file_paths_processed;
                app.DataInput.Value = app.DataInput.ItemsData{end};
                %signals DataInput has changed
                dummyEvent = struct('Source', app.DataInput, 'Value', app.DataInput.Value);
                DataInputChanged(app, dummyEvent); % call the function with the dummy event object
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Processar = uitab(app.TabGroup);
            app.Processar.Title = 'Processar';
            app.ProcessarButton = uibutton(app.Processar, 'push');
            app.ProcessarButton.ButtonPushedFcn = createCallbackFcn(app, @ProcessarButtonPushed, true);
            app.ProcessarButton.Text = 'Processar';
            app.X1EditFieldLabel = uilabel(app.Processar);
            app.X1EditFieldLabel.Text = 'X1';
            app.X1EditField = uieditfield(app.Processar, 'numeric');
            app.X1EditField.ValueDisplayFormat = '%5.16g';
            app.Y1EditFieldLabel = uilabel(app.Processar);
            app.Y1EditFieldLabel.Text = 'Y1';
            app.Y1EditField = uieditfield(app.Processar, 'numeric');
            app.Y1EditField.ValueDisplayFormat = '%5.16g';
            app.X2EditFieldLabel = uilabel(app.Processar);
            app.X2EditFieldLabel.Text = 'X2';
            app.X2EditField = uieditfield(app.Processar, 'numeric');
            app.X2EditField.ValueDisplayFormat = '%5.16g';
            app.Y2EditFieldLabel = uilabel(app.Processar);
            app.Y2EditFieldLabel.Text = 'Y2';
            app.Y2EditField = uieditfield(app.Processar, 'numeric');
            app.Y2EditField.ValueDisplayFormat = '%5.16g';
            app.CordenadasdaMetaLabel = uilabel(app.Processar);
            app.CordenadasdaMetaLabel.Text = 'Cordenadas da Meta';
            app.FicheirosaprocessarLabel = uilabel(app.Processar);
            app.FicheirosaprocessarLabel.Text = 'Ficheiros  a processar:';
            app.FicheirosaprocessarTextArea = uitextarea(app.Processar);
            app.FicheirosaprocessarTextArea.Editable = 'off';
            app.ImportarFicheirosButton = uibutton(app.Processar, 'push');
            app.ImportarFicheirosButton.ButtonPushedFcn = createCallbackFcn(app, @ImportarFicheirosButtonPushed, true);
            app.ImportarFicheirosButton.Text = 'Importar Ficheiros';
            app.GraficosTab = uitab(app.TabGroup);
            app.GraficosTab.Title = 'Graficos';
            app.UIAxes2 = uiaxes(app.GraficosTab);
            title(app.UIAxes2, 'GPS')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            app.UIAxes = uiaxes(app.GraficosTab);
            title(app.UIAxes, 'Importe um ficheiro .mat processado primeiro')
            xlabel(app.UIAxes, 'X')
            app.EixoYListBox = uilistbox(app.GraficosTab);
            app.EixoYListBox.Items = {'Torque [N.m]', 'Battery Assembly Voltage [V]', 'GPS', 'Speed [Km/h]', 'Target id [A]', 'Target iq [A]', 'id [A]', 'iq [A]', 'Ud [V]', 'Uq [V]', 'Voltage modulation', 'AC Voltage [V]', 'AC Current [A]', 'Max iq allowed [A]', 'Motor Temperature [°C]', 'Battery Voltage (Controller) [V]', 'Battery Current (Controller) [A]', 'Cap Voltage [V]', 'Heatsink Temperature [°C]', 'Engine RPM', 'Throttle Voltage [V]', 'Throttle', 'Battery Pack-1 Voltage [V]', 'Battery Pack-2 Voltage [V]', 'Battery Pack-3 Voltage [V]', 'Battery Pack-4 Voltage [V]', 'Battery Pack-5 Voltage [V]', 'Battery maximum temperature [°C]', 'Pack-1 V1 [V]', 'Pack-1 V2 [V]', 'Pack-1 V3 [V]', 'Pack-1 V4 [V]', 'Pack-1 V5 [V]', 'Pack-1 V6 [V]', 'Pack-2 V1 [V]', 'Pack-2 V2 [V]', 'Pack-2 V3 [V]', 'Pack-2 V4 [V]', 'Pack-2 V5 [V]', 'Pack-3 V1 [V]', 'Pack-3 V2 [V]', 'Pack-3 V3 [V]', 'Pack-3 V4 [V]', 'Pack-3 V5 [V]', 'Pack-4 V1 [V]', 'Pack-4 V2 [V]', 'Pack-4 V3 [V]', 'Pack-4 V5 [V]', 'Pack-5 V1 [V]', 'Pack-5 V2 [V]', 'Pack-5 V3 [V]', 'Pack-5 V4 [V]', 'Pack-5 V5 [V]'};
            app.EixoYListBox.Multiselect = 'on';
            app.EixoYListBox.ValueChangedFcn = createCallbackFcn(app, @EixoYListBoxValueChanged, true);
            app.EixoYListBox.Value = {'Torque [N.m]'};
            app.EixoYListBoxLabel = uilabel(app.GraficosTab);
            app.EixoYListBoxLabel.Text = 'Eixo Y';
            app.FileLabel = uilabel(app.GraficosTab);
            app.FileLabel.Text = 'File';
            app.DataInput = uidropdown(app.GraficosTab);
            app.DataInput.Items = {};
            app.DataInput.ValueChangedFcn = createCallbackFcn(app, @DataInputChanged, true);
            app.DataInput.Value = {};
            app.VoltaSpinnerLabel = uilabel(app.GraficosTab);
            app.VoltaSpinnerLabel.Text = 'Volta';
            app.VoltaSpinner = uispinner(app.GraficosTab);
            app.VoltaSpinner.RoundFractionalValues = 'on';
            app.VoltaSpinner.ValueChangedFcn = createCallbackFcn(app, @VoltaSpinnerValueChanged, true);
            app.VoltaSpinner.Value = 1;
            app.TrackButton = uibutton(app.GraficosTab, 'push');
            app.TrackButton.ButtonPushedFcn = createCallbackFcn(app, @TrackButtonPushed, true);
            app.TrackButton.Text = 'Track';
            app.AplicaodeTelemetriaLabel = uilabel(app.GraficosTab);
            app.AplicaodeTelemetriaLabel.Text = 'Aplicação de Telemetria';
            app.ImportFileButton = uibutton(app.GraficosTab, 'push');
            app.ImportFileButton.ButtonPushedFcn = createCallbackFcn(app, @ImportFileButtonPushed, true);
            app.ImportFileButton.Text = 'Import File';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end